classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        correctallPanel               matlab.ui.container.Panel
        startoverallcorrectionButton  matlab.ui.control.Button
        BrowseButton_export           matlab.ui.control.Button
        exportdirEditField            matlab.ui.control.EditField
        exportdirEditFieldLabel       matlab.ui.control.Label
        correctionPanel               matlab.ui.container.Panel
        DeltaEmeVEditField            matlab.ui.control.NumericEditField
        DeltaEmeVEditFieldLabel       matlab.ui.control.Label
        EnergyeVEditField             matlab.ui.control.NumericEditField
        EnergyeVEditFieldLabel        matlab.ui.control.Label
        PmeasuredTextArea             matlab.ui.control.TextArea
        PmeasuredTextAreaLabel        matlab.ui.control.Label
        BrowseButton_P                matlab.ui.control.Button
        MmeasuredTextArea             matlab.ui.control.TextArea
        MmeasuredTextAreaLabel        matlab.ui.control.Label
        BrowseButton_M                matlab.ui.control.Button
        viewcorrectionButton          matlab.ui.control.Button
        currentASpinner               matlab.ui.control.Spinner
        currentASpinnerLabel          matlab.ui.control.Label
        thetaZSpinner                 matlab.ui.control.Spinner
        thetaZSpinnerLabel            matlab.ui.control.Label
        thetaYSpinner                 matlab.ui.control.Spinner
        thetaYSpinnerLabel            matlab.ui.control.Label
        thetaXSpinner                 matlab.ui.control.Spinner
        thetaXSpinnerLabel            matlab.ui.control.Label
        transZSpinner                 matlab.ui.control.Spinner
        transZSpinnerLabel            matlab.ui.control.Label
        transYSpinner                 matlab.ui.control.Spinner
        transYSpinnerLabel            matlab.ui.control.Label
        transXSpinner                 matlab.ui.control.Spinner
        transXSpinnerLabel            matlab.ui.control.Label
        standardfieldPanel            matlab.ui.container.Panel
        DescriptionTextArea           matlab.ui.control.TextArea
        DescriptionTextAreaLabel      matlab.ui.control.Label
        startbuildingstandardfieldButton  matlab.ui.control.Button
        plottheconfigurationCheckBox  matlab.ui.control.CheckBox
        ztargetmthemaximumzofsimulationSpinner  matlab.ui.control.Spinner
        ztargetmthemaximumzofsimulationLabel  matlab.ui.control.Label
        importdataPanel               matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        igorh5Tab                     matlab.ui.container.Tab
        thetaydeltaEditField          matlab.ui.control.NumericEditField
        thetaydeltaEditFieldLabel     matlab.ui.control.Label
        thetayoffsetEditField         matlab.ui.control.NumericEditField
        thetayoffsetEditFieldLabel    matlab.ui.control.Label
        thetaxoffsetEditField         matlab.ui.control.NumericEditField
        thetaxoffsetEditFieldLabel    matlab.ui.control.Label
        thetaxdeltaEditField          matlab.ui.control.NumericEditField
        thetaxdeltaEditFieldLabel     matlab.ui.control.Label
        energydeltaEditField          matlab.ui.control.NumericEditField
        energydeltaEditFieldLabel     matlab.ui.control.Label
        energyoffsetEditField         matlab.ui.control.NumericEditField
        energyoffsetEditFieldLabel    matlab.ui.control.Label
        thetaynumEditField            matlab.ui.control.NumericEditField
        thetaynumLabel                matlab.ui.control.Label
        thetaxnumEditField            matlab.ui.control.NumericEditField
        thetaxnumLabel                matlab.ui.control.Label
        energynumEditField            matlab.ui.control.NumericEditField
        energynumLabel                matlab.ui.control.Label
        EfeVSpinner                   matlab.ui.control.Spinner
        EfeVSpinnerLabel              matlab.ui.control.Label
        readdataButton                matlab.ui.control.Button
        outputBrowseButton            matlab.ui.control.Button
        outputpathEditField           matlab.ui.control.EditField
        outputpathEditFieldLabel      matlab.ui.control.Label
        dataBrowseButton              matlab.ui.control.Button
        datatypeDropDown              matlab.ui.control.DropDown
        datatypeDropDownLabel         matlab.ui.control.Label
        datapathEditField             matlab.ui.control.EditField
        datapathEditFieldLabel        matlab.ui.control.Label
        ModesButtonGroup              matlab.ui.container.ButtonGroup
        correctionButton              matlab.ui.control.ToggleButton
        correctallButton              matlab.ui.control.ToggleButton
        importdataButton              matlab.ui.control.ToggleButton
        standardfieldButton           matlab.ui.control.ToggleButton
        RightPanel                    matlab.ui.container.Panel
        slit_demo                     matlab.ui.control.Image
        BcenterLabel                  matlab.ui.control.Label
        manipulator_demo              matlab.ui.control.Image
        k_rAA1Spinner                 matlab.ui.control.Spinner
        k_rradiusofthecentralareabeingplottedLabel  matlab.ui.control.Label
        UIAxes_geometry               matlab.ui.control.UIAxes
        UIAxes_band                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        bands_P
        bands_M
        filepath_P
        filepath_M
        standard_field
        geometry
        transform
    end
    
    methods (Access = private)
        function corrected_diff_app(app, k_r, corrected_plus, corrected_minus)
            % k_r=1.5
            kx_plus = corrected_plus.kx;
            ky_plus = corrected_plus.ky;
            try
                I_plus = corrected_plus.I;
            catch
                I_plus = corrected_plus.I_thetax_thetay;
            end
            kx_minus = corrected_minus.kx;
            ky_minus = corrected_minus.ky;
            try
                I_minus = corrected_minus.I;
            catch 
                I_minus = corrected_minus.I_thetax_thetay;
            end
            if ~isa(I_plus, 'double') 
                I_plus = double(I_plus);
            end
            if ~isa(I_minus, 'double')
                I_minus = double(I_minus);
            end
        
            center_mask_plus = (kx_plus.^2 + ky_plus.^2 < k_r^2);
            kx_plus_ROI = kx_plus(center_mask_plus);
            ky_plus_ROI = ky_plus(center_mask_plus);
            I_plus_ROI = I_plus(center_mask_plus);
            
            F_minus = scatteredInterpolant(kx_minus(:),ky_minus(:),I_minus(:),'linear','none');
            I_minus_ROI_intp = F_minus(kx_plus_ROI, ky_plus_ROI);
            clean_mask = ~isnan(I_minus_ROI_intp) & ~isnan(I_plus_ROI);
            scatter(app.UIAxes_band, kx_plus_ROI(clean_mask), ky_plus_ROI(clean_mask), 10, I_plus_ROI(clean_mask)-I_minus_ROI_intp(clean_mask),'filled');
            axis(app.UIAxes_band, 'equal', 'tight');
            xlabel(app.UIAxes_band, 'k_x [10^{10} m^{-1}]');
            ylabel(app.UIAxes_band, 'k_y [10^{10} m^{-1}]');
            title(app.UIAxes_band, 'Difference between corrected +field and -field fermi surface');
            colormap(app.UIAxes_band, turbo); colorbar(app.UIAxes_band);              
        end

        function plot_geometry(app)
            gm = app.geometry;
            thetaZ = - app.transform.thetaZ;
            thetaY = - app.transform.thetaY;
            thetaX = - app.transform.thetaX;

            % rotate CW around Y,X,Z in order, axes being attached to the geometry! 
            % So the order is inversed!
            gm = rotate(gm, thetaZ, [0,0,0], [0,0,1]);
            gm = rotate(gm, thetaX, [0,0,0], [1,0,0]);
            gm = rotate(gm, thetaY, [0,0,0], [0,1,0]);
            gm = translate(gm, [app.transform.transX, app.transform.transY, app.transform.transZ]);

            pdegplot(gm, 'Parent', app.UIAxes_geometry);
            view(app.UIAxes_geometry, 3); 
            xlim(app.UIAxes_geometry, [-2e-3 2e-3]); % X-axis window
            ylim(app.UIAxes_geometry, [-2e-3 2e-3]); % Y-axis window
            zlim(app.UIAxes_geometry, [-1e-4 1e-4]); % Z-axis window
            axis(app.UIAxes_geometry, 'tight'); % Fit axes to geometry bounds
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Geometry Demo for correction mode
            % Geometry parameters
            coilDin       = 6.3e-3;
            coilDout      = 12e-3;
            coilHeight    = 5.6e-3;
            shieldDout    = 13e-3;
            shieldDin     = 4e-3;
            shieldHeight  = 0.25e-3;
            coreDout1     = 5.334e-3;
            coreDin       = 2.286e-3;
            coreDout2     = 3.5e-3;
            coreHeight1   = 2.794e-3;
            coreHeight2   = 3.048e-3;
            gapCoilCore   = 1.28e-3;
            gapCoilShield = 2e-4;
            grooveLength  = 1.016e-3;
        
            % No rotation/translation (standard configuration)
            app.transform.transX=0;
            app.transform.transY=0;
            app.transform.transZ=0;
            app.transform.thetaX=0;
            app.transform.thetaY=0;
            app.transform.thetaZ=0;
            
            % Build geometry
            shieldGm = multicylinder([shieldDin/2, shieldDout/2], shieldHeight, Void=[1 0]);
            shieldGm = translate(shieldGm, [0,0,-shieldHeight + gapCoilShield]);
        
            coilGm = multicylinder([coilDin/2, coilDout/2], coilHeight, Void=[1 0]);
            coilGm = translate(coilGm, [0,0,-shieldHeight-coilHeight]);
                
            coreGm1 = multicylinder([coreDin/2, coreDout1/2], coreHeight1, Void=[1 0]);
            coreGm2 = multicylinder([coreDin/2, coreDout2/2], coreHeight2, Void=[1 0]);
            coreGm1 = translate(coreGm1, [0,0,-shieldHeight-coreHeight1-gapCoilCore]);
            coreGm2 = translate(coreGm2, [0,0,-shieldHeight-coreHeight1-coreHeight2-gapCoilCore+2e-5]);
            % 2e-5 is to make the cores overlapped so that later we can combine
            % them
            cuttingBox = multicuboid(grooveLength, coreDout1, grooveLength);
            cuttingBox = translate(cuttingBox, [0, 0, -shieldHeight-gapCoilCore-grooveLength/2]);
        
            coreGm1 = fegeometry(coreGm1);
            coreGm2 = fegeometry(coreGm2);
            shieldGm = fegeometry(shieldGm);
            coilGm   = fegeometry(coilGm);
            cuttingBox = fegeometry(cuttingBox);
            groovedCoreGm = subtract(union(coreGm1, coreGm2), cuttingBox);
        
            % collect all the geometry
            gm = union(groovedCoreGm, coilGm);
            gm = union(gm, shieldGm);
            app.geometry = gm;

            pdegplot(app.geometry, 'Parent', app.UIAxes_geometry);
            view(app.UIAxes_geometry, 3); % [azimuth elevation] vector
            % Set custom limits
            xlim(app.UIAxes_geometry, [-2e-3 2e-3]); % X-axis window
            ylim(app.UIAxes_geometry, [-2e-3 2e-3]); % Y-axis window
            zlim(app.UIAxes_geometry, [-1e-4 1e-4]); % Z-axis window
            axis(app.UIAxes_geometry, 'tight'); % Fit axes to geometry bounds
        end

        % Button pushed function: viewcorrectionButton
        function viewcorrectionButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please Wait', ...
                'Message','Running analysis...', ...
                'Indeterminate','on');
            parameters.transX = app.transXSpinner.Value;
            parameters.transY = app.transYSpinner.Value;
            parameters.transZ = app.transZSpinner.Value;
            parameters.thetaX = app.thetaXSpinner.Value*pi/180;
            parameters.thetaY = app.thetaYSpinner.Value*pi/180;
            parameters.thetaZ = app.thetaZSpinner.Value*pi/180;
            parameters.current = app.currentASpinner.Value;
            energy = app.EnergyeVEditField.Value;
            delta_E = app.DeltaEmeVEditField.Value / 1000;  % notice meV
            k_r = app.k_rAA1Spinner.Value;
            if isempty(app.PmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of P measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.MmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of M measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.standard_field)
                try 
                    app.standard_field = load('./matdata/standard_field_withShield.mat');
                catch 
                    uialert(app.UIFigure, ...
                        'Please make sure there is standard_field.mat under the current directory',...
                        'Error');
                    return;
                end
            end
            if ~strcmp(app.filepath_P, string(app.PmeasuredTextArea.Value))
                app.filepath_P = string(app.PmeasuredTextArea.Value);
                app.bands_P = load(string(app.PmeasuredTextArea.Value));
            end
            if ~strcmp(app.filepath_M, string(app.MmeasuredTextArea.Value))
                app.filepath_M = string(app.MmeasuredTextArea.Value);
                app.bands_M = load(string(app.MmeasuredTextArea.Value));
            end
            idx_P = find(app.bands_P.E >= energy - delta_E/2 & app.bands_P.E <= energy + delta_E/2);  % this is a list of indices
            idx_M = find(app.bands_M.E >= energy - delta_E/2 & app.bands_M.E <= energy + delta_E/2);
            idx_P_middle = idx_P(ceil(numel(idx_P)/2));
            idx_M_middle = idx_M(ceil(numel(idx_M)/2));
            fs_P.kx = squeeze(app.bands_P.kx(idx_P_middle, :, :));
            fs_P.ky = squeeze(app.bands_P.ky(idx_P_middle, :, :));
            fs_P.I_thetax_thetay = squeeze(mean(app.bands_P.I_thetax_thetay(idx_P, :, :), 1));
            fs_M.kx = squeeze(app.bands_M.kx(idx_M_middle, :, :));
            fs_M.ky = squeeze(app.bands_M.ky(idx_M_middle, :, :));
            fs_M.I_thetax_thetay = squeeze(mean(app.bands_M.I_thetax_thetay(idx_M, :, :), 1));
            
            [Fkx_plus, Fky_plus] = calc_inverse_mapping(app.standard_field, energy, parameters, false);% angles in rad
            parameters.current = - parameters.current;
            [Fkx_minus, Fky_minus] = calc_inverse_mapping(app.standard_field, energy, parameters, false);
            
            corrected_plus = backward_mapping(fs_P, Fkx_plus, Fky_plus, false);   % .kx .ky .I are vectors
            corrected_minus = backward_mapping(fs_M, Fkx_minus, Fky_minus, false);% .kx .ky .I are vectors
            corrected_diff_app(app, k_r, corrected_plus, corrected_minus);
            % show the field at the center given the input parameters
            parameters.current = - parameters.current;
            center_field = 1000 * calc_center_field(app.standard_field, parameters, [0,0,0]);  % 3-vector of the field at the center
            output_str = sprintf('B(center) (mT) = (%.2f, %.2f, %.2f)', center_field(1), center_field(2), center_field(3));
            app.BcenterLabel.Text = output_str;
            close(d);
        end

        % Button pushed function: BrowseButton_P
        function BrowseButton_PPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the .mat data file of plus field measured bands');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.PmeasuredTextArea.Value = fullPath;
            end
        end

        % Button pushed function: BrowseButton_M
        function BrowseButton_MPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the .mat data file of minus field measured bands');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.MmeasuredTextArea.Value = fullPath;
            end
        end

        % Selection changed function: ModesButtonGroup
        function ModesButtonGroupSelectionChanged(app, event)
            switch app.ModesButtonGroup.SelectedObject.Text
                case 'standard field'
                    app.standardfieldPanel.Visible = 'on';
                    app.importdataPanel.Visible = 'off';
                    app.correctionPanel.Visible = 'off';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'off';
                case 'correction'
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'off';
                    app.correctionPanel.Visible = 'on';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'off';
                case 'import data'
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'on';
                    app.correctionPanel.Visible = 'off';
                    app.UIAxes_geometry.Visible = 'off';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'on';
                    app.correctallPanel.Visible = 'off';
                case 'correct all'
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'on';
                    app.correctionPanel.Visible = 'on';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'on';
            end
        end

        % Button pushed function: startbuildingstandardfieldButton
        function startbuildingstandardfieldButtonPushed(app, event)
            calc_standard_field(app.ztargetmthemaximumzofsimulationSpinner.Value, app.plottheconfigurationCheckBox.Value);
        end

        % Value changed function: currentASpinner
        function currentASpinnerValueChanged(app, event)
            value = app.currentASpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.currentASpinner.Step = step;
        end

        % Value changed function: thetaZSpinner
        function thetaZSpinnerValueChanged(app, event)
            value = app.thetaZSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.thetaZSpinner.Step = step;
            app.transform.thetaZ = value;
            plot_geometry(app);
        end

        % Value changed function: thetaYSpinner
        function thetaYSpinnerValueChanged(app, event)
            value = app.thetaYSpinner.Value;
            step = max(0.01*abs(value),1d-8);
            app.thetaYSpinner.Step = step;
            app.transform.thetaY = value;
            plot_geometry(app);
        end

        % Value changed function: thetaXSpinner
        function thetaXSpinnerValueChanged(app, event)
            value = app.thetaXSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.thetaXSpinner.Step = step;
            app.transform.thetaX = value;
            plot_geometry(app);
        end

        % Value changed function: transZSpinner
        function transZSpinnerValueChanged(app, event)
            value = app.transZSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.transZSpinner.Step = step;
            app.transform.transZ = value;
            plot_geometry(app);
        end

        % Value changed function: transYSpinner
        function transYSpinnerValueChanged(app, event)
            value = app.transYSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.transYSpinner.Step = step;
            app.transform.transY = value;
            plot_geometry(app);
        end

        % Value changed function: transXSpinner
        function transXSpinnerValueChanged(app, event)
            value = app.transXSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.transXSpinner.Step = step;
            app.transform.transX = value;
            plot_geometry(app);
        end

        % Image clicked function: manipulator_demo
        function manipulator_demoClicked(app, event)
            I = imread('./figures/manipulator_demo.png');
            figure; imshow(I);
        end

        % Image clicked function: slit_demo
        function slit_demoImageClicked(app, event)
            I = imread('./figures/slit_demo.png');
            figure; imshow(I);
        end

        % Button pushed function: dataBrowseButton
        function dataBrowseButtonPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the .h5 file generated by Igor');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.datapathEditField.Value = fullPath;   % char
            end
        end

        % Button pushed function: outputBrowseButton
        function outputBrowseButtonPushed(app, event)
            folderPath = uigetdir(pwd, 'Choose the folder to hold the corresponding .mat file');
            if folderPath ~= 0
                app.outputpathEditField.Value = folderPath;
            end
        end

        % Value changed function: datatypeDropDown
        function datatypeDropDownValueChanged(app, event)
            value = app.datatypeDropDown.Value;
            switch string(value)
                case "raw .h5"
                    app.TabGroup.Visible = 'off';
                case "Igor .h5"
                    app.TabGroup.Visible = 'on';
                case "to be implemented"
                    error('this part is unavailable right now');
            end
        end

        % Button pushed function: readdataButton
        function readdataButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please Wait', ...
                'Message','Running analysis...', ...
                'Indeterminate','on');
            [~, filename, ~] = fileparts(string(app.datapathEditField.Value));
            switch string(app.datatypeDropDown.Value)
                case "raw .h5"
                    read_h5(string(app.datapathEditField.Value), fullfile(app.outputpathEditField.Value, filename+".mat"));
                case "Igor .h5"
                    read_igor_h5(app.datapathEditField.Value, fullfile(app.outputpathEditField.Value, filename+".mat"), ...
                        app.energynumEditField.Value, app.thetaxnumEditField.Value, app.thetaynumEditField.Value, ...
                        app.energydeltaEditField.Value, app.thetaxdeltaEditField.Value, app.thetaydeltaEditField.Value, ...
                        app.energyoffsetEditField.Value, app.thetaxoffsetEditField.Value, app.thetayoffsetEditField.Value, ...
                        app.EfeVSpinner.Value);
            end
            close(d);
        end

        % Button pushed function: BrowseButton_export
        function BrowseButton_exportPushed(app, event)
            folderPath = uigetdir(pwd, 'Choose the folder to hold two corrected data as .bin files');
            if folderPath ~= 0
                app.exportdirEditField.Value = folderPath;
            end
        end

        % Button pushed function: startoverallcorrectionButton
        function startoverallcorrectionButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);


            [~, filename_P, ~] = fileparts(app.PmeasuredTextArea.Value);
            [~, filename_M, ~] = fileparts(app.MmeasuredTextArea.Value);

            parameters.transX = app.transXSpinner.Value;
            parameters.transY = app.transYSpinner.Value;
            parameters.transZ = app.transZSpinner.Value;
            parameters.thetaX = app.thetaXSpinner.Value;  % use degree!
            parameters.thetaY = app.thetaYSpinner.Value;
            parameters.thetaZ = app.thetaZSpinner.Value;
            parameters.current = app.currentASpinner.Value;

            if isempty(app.PmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of P measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.MmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of M measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.standard_field)
                try 
                    app.standard_field = load('./matdata/standard_field_withShield.mat');
                catch 
                    uialert(app.UIFigure, ...
                        'Please make sure there is standard_field.mat under the current directory',...
                        'Error');
                    return;
                end
            end
            if ~strcmp(app.filepath_P, string(app.PmeasuredTextArea.Value))
                app.filepath_P = string(app.PmeasuredTextArea.Value);
                app.bands_P = load(string(app.PmeasuredTextArea.Value));
            end
            if ~strcmp(app.filepath_M, string(app.MmeasuredTextArea.Value))
                app.filepath_M = string(app.MmeasuredTextArea.Value);
                app.bands_M = load(string(app.MmeasuredTextArea.Value));
            end

            % angles in degree
            build_bin(fullfile(app.exportdirEditField.Value, filename_P+"_Corrected.bin"), app.bands_P, app.standard_field, parameters);
            d.Value = 0.5;          % halfway done
            d.Message = 'P bands correction finished. Running M bands correction...';
            drawnow;                % force UI update

            parameters.current = -parameters.current;
            build_bin(fullfile(app.exportdirEditField.Value, filename_M+"_Corrected.bin"), app.bands_M, app.standard_field, parameters);
            close(d);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModesButtonGroup = uibuttongroup(app.LeftPanel);
            app.ModesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModesButtonGroupSelectionChanged, true);
            app.ModesButtonGroup.Title = 'Modes';
            app.standardfieldButton = uitogglebutton(app.ModesButtonGroup);
            app.standardfieldButton.Text = 'standard field';
            app.standardfieldButton.Value = true;
            app.importdataButton = uitogglebutton(app.ModesButtonGroup);
            app.importdataButton.Text = 'import data';
            app.correctallButton = uitogglebutton(app.ModesButtonGroup);
            app.correctallButton.Text = 'correct all';
            app.correctionButton = uitogglebutton(app.ModesButtonGroup);
            app.correctionButton.Text = 'correction';
            app.importdataPanel = uipanel(app.LeftPanel);
            app.importdataPanel.Title = 'import data';
            app.importdataPanel.Visible = 'off';
            app.datapathEditFieldLabel = uilabel(app.importdataPanel);
            app.datapathEditFieldLabel.Text = 'data path';
            app.datapathEditField = uieditfield(app.importdataPanel, 'text');
            app.datatypeDropDownLabel = uilabel(app.importdataPanel);
            app.datatypeDropDownLabel.Text = 'data type';
            app.datatypeDropDown = uidropdown(app.importdataPanel);
            app.datatypeDropDown.Items = {'raw .h5', 'Igor .h5', 'to be implemented'};
            app.datatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @datatypeDropDownValueChanged, true);
            app.datatypeDropDown.Value = 'raw .h5';
            app.dataBrowseButton = uibutton(app.importdataPanel, 'push');
            app.dataBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @dataBrowseButtonPushed, true);
            app.dataBrowseButton.Text = 'Browse';
            app.outputpathEditFieldLabel = uilabel(app.importdataPanel);
            app.outputpathEditFieldLabel.Text = 'output path';
            app.outputpathEditField = uieditfield(app.importdataPanel, 'text');
            app.outputpathEditField.Tooltip = {'The path of the folder to store the output .mat file'};
            app.outputBrowseButton = uibutton(app.importdataPanel, 'push');
            app.outputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @outputBrowseButtonPushed, true);
            app.outputBrowseButton.Text = 'Browse';
            app.readdataButton = uibutton(app.importdataPanel, 'push');
            app.readdataButton.ButtonPushedFcn = createCallbackFcn(app, @readdataButtonPushed, true);
            app.readdataButton.Text = 'read data';
            app.TabGroup = uitabgroup(app.importdataPanel);
            app.TabGroup.Visible = 'off';
            app.igorh5Tab = uitab(app.TabGroup);
            app.igorh5Tab.Title = 'igor .h5';
            app.EfeVSpinnerLabel = uilabel(app.igorh5Tab);
            app.EfeVSpinnerLabel.Text = 'Ef (eV)';
            app.EfeVSpinner = uispinner(app.igorh5Tab);
            app.energynumLabel = uilabel(app.igorh5Tab);
            app.energynumLabel.Text = 'energy: num';
            app.energynumEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaxnumLabel = uilabel(app.igorh5Tab);
            app.thetaxnumLabel.Text = 'thetax: num';
            app.thetaxnumEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaynumLabel = uilabel(app.igorh5Tab);
            app.thetaynumLabel.Text = 'thetay: num';
            app.thetaynumEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.energyoffsetEditFieldLabel = uilabel(app.igorh5Tab);
            app.energyoffsetEditFieldLabel.Text = 'offset';
            app.energyoffsetEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.energydeltaEditFieldLabel = uilabel(app.igorh5Tab);
            app.energydeltaEditFieldLabel.Text = 'delta';
            app.energydeltaEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaxdeltaEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetaxdeltaEditFieldLabel.Text = 'delta';
            app.thetaxdeltaEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaxoffsetEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetaxoffsetEditFieldLabel.Text = 'offset';
            app.thetaxoffsetEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetayoffsetEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetayoffsetEditFieldLabel.Text = 'offset';
            app.thetayoffsetEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaydeltaEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetaydeltaEditFieldLabel.Text = 'delta';
            app.thetaydeltaEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.standardfieldPanel = uipanel(app.LeftPanel);
            app.standardfieldPanel.Title = 'standard field';
            app.standardfieldPanel.Visible = 'off';
            app.ztargetmthemaximumzofsimulationLabel = uilabel(app.standardfieldPanel);
            app.ztargetmthemaximumzofsimulationLabel.Text = 'z target (m): the maximum z of simulation.';
            app.ztargetmthemaximumzofsimulationSpinner = uispinner(app.standardfieldPanel);
            app.ztargetmthemaximumzofsimulationSpinner.Value = 0.016;
            app.plottheconfigurationCheckBox = uicheckbox(app.standardfieldPanel);
            app.plottheconfigurationCheckBox.Text = 'plot the configuration';
            app.startbuildingstandardfieldButton = uibutton(app.standardfieldPanel, 'push');
            app.startbuildingstandardfieldButton.ButtonPushedFcn = createCallbackFcn(app, @startbuildingstandardfieldButtonPushed, true);
            app.startbuildingstandardfieldButton.Text = 'start building standard field';
            app.DescriptionTextAreaLabel = uilabel(app.standardfieldPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.standardfieldPanel);
            app.DescriptionTextArea.Editable = 'off';
            app.DescriptionTextArea.Value = {'standard field mode is used to simulate the field with the coil, core, and shield. The result is stored as ./matdata/standard_field.mat. If there is alredy such a .mat file, you don''t need to simulate the field again.'; '(notice this will usually take long time)'};
            app.correctionPanel = uipanel(app.LeftPanel);
            app.correctionPanel.Title = 'correction';
            app.transXSpinnerLabel = uilabel(app.correctionPanel);
            app.transXSpinnerLabel.Text = 'transX';
            app.transXSpinner = uispinner(app.correctionPanel);
            app.transXSpinner.ValueChangedFcn = createCallbackFcn(app, @transXSpinnerValueChanged, true);
            app.transYSpinnerLabel = uilabel(app.correctionPanel);
            app.transYSpinnerLabel.Text = 'transY';
            app.transYSpinner = uispinner(app.correctionPanel);
            app.transYSpinner.ValueChangedFcn = createCallbackFcn(app, @transYSpinnerValueChanged, true);
            app.transZSpinnerLabel = uilabel(app.correctionPanel);
            app.transZSpinnerLabel.Text = 'transZ';
            app.transZSpinner = uispinner(app.correctionPanel);
            app.transZSpinner.ValueChangedFcn = createCallbackFcn(app, @transZSpinnerValueChanged, true);
            app.thetaXSpinnerLabel = uilabel(app.correctionPanel);
            app.thetaXSpinnerLabel.Text = 'thetaX';
            app.thetaXSpinner = uispinner(app.correctionPanel);
            app.thetaXSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaXSpinnerValueChanged, true);
            app.thetaYSpinnerLabel = uilabel(app.correctionPanel);
            app.thetaYSpinnerLabel.Text = 'thetaY';
            app.thetaYSpinner = uispinner(app.correctionPanel);
            app.thetaYSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaYSpinnerValueChanged, true);
            app.thetaZSpinnerLabel = uilabel(app.correctionPanel);
            app.thetaZSpinnerLabel.Text = 'thetaZ';
            app.thetaZSpinner = uispinner(app.correctionPanel);
            app.thetaZSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaZSpinnerValueChanged, true);
            app.currentASpinnerLabel = uilabel(app.correctionPanel);
            app.currentASpinnerLabel.Text = 'current(A)';
            app.currentASpinner = uispinner(app.correctionPanel);
            app.currentASpinner.ValueChangedFcn = createCallbackFcn(app, @currentASpinnerValueChanged, true);
            app.viewcorrectionButton = uibutton(app.correctionPanel, 'push');
            app.viewcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @viewcorrectionButtonPushed, true);
            app.viewcorrectionButton.Tooltip = {'Show the corrected pattern of the Energy chosen, to finetune the parameters'};
            app.viewcorrectionButton.Text = 'view correction';
            app.BrowseButton_M = uibutton(app.correctionPanel, 'push');
            app.BrowseButton_M.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_MPushed, true);
            app.BrowseButton_M.Text = 'Browse';
            app.MmeasuredTextAreaLabel = uilabel(app.correctionPanel);
            app.MmeasuredTextAreaLabel.Text = 'M measured';
            app.MmeasuredTextArea = uitextarea(app.correctionPanel);
            app.MmeasuredTextArea.Tooltip = {'.mat file containing 3D k-bands generated by importing minus field data'};
            app.BrowseButton_P = uibutton(app.correctionPanel, 'push');
            app.BrowseButton_P.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_PPushed, true);
            app.BrowseButton_P.Text = 'Browse';
            app.PmeasuredTextAreaLabel = uilabel(app.correctionPanel);
            app.PmeasuredTextAreaLabel.Text = 'P measured';
            app.PmeasuredTextArea = uitextarea(app.correctionPanel);
            app.PmeasuredTextArea.Tooltip = {'.mat file containing 3D k-bands generated by importing plus field data'};
            app.EnergyeVEditFieldLabel = uilabel(app.correctionPanel);
            app.EnergyeVEditFieldLabel.Text = 'Energy(eV)';
            app.EnergyeVEditField = uieditfield(app.correctionPanel, 'numeric');
            app.EnergyeVEditField.Limits = [0 Inf];
            app.EnergyeVEditField.ValueDisplayFormat = '%11.5g';
            app.EnergyeVEditField.Tooltip = {'The slice over which correction is made to examine the difference on the right figure. Usually fermi energy. '};
            app.EnergyeVEditField.Value = 110.56;
            app.DeltaEmeVEditFieldLabel = uilabel(app.correctionPanel);
            app.DeltaEmeVEditFieldLabel.Tooltip = {'take average in this range around "Energy"'};
            app.DeltaEmeVEditFieldLabel.Interpreter = 'latex';
            app.DeltaEmeVEditFieldLabel.Text = '\Delta E (meV)';
            app.DeltaEmeVEditField = uieditfield(app.correctionPanel, 'numeric');
            app.DeltaEmeVEditField.Limits = [0 Inf];
            app.DeltaEmeVEditField.Tooltip = {'The slice over which correction is made to examine the difference on the right figure. Usually fermi energy. '};
            app.DeltaEmeVEditField.Value = 10;
            app.correctallPanel = uipanel(app.LeftPanel);
            app.correctallPanel.Title = 'correct all';
            app.correctallPanel.Visible = 'off';
            app.exportdirEditFieldLabel = uilabel(app.correctallPanel);
            app.exportdirEditFieldLabel.Text = 'export dir';
            app.exportdirEditField = uieditfield(app.correctallPanel, 'text');
            app.BrowseButton_export = uibutton(app.correctallPanel, 'push');
            app.BrowseButton_export.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_exportPushed, true);
            app.BrowseButton_export.Text = 'Browse';
            app.startoverallcorrectionButton = uibutton(app.correctallPanel, 'push');
            app.startoverallcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @startoverallcorrectionButtonPushed, true);
            app.startoverallcorrectionButton.Tooltip = {'Correct the two P/M bands below, using the parameters below. This may take some time. Corrected bands are stored as .bin'};
            app.startoverallcorrectionButton.Text = 'start overall correction';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_band = uiaxes(app.RightPanel);
            title(app.UIAxes_band, 'Title')
            xlabel(app.UIAxes_band, 'X')
            ylabel(app.UIAxes_band, 'Y')
            zlabel(app.UIAxes_band, 'Z')
            app.UIAxes_geometry = uiaxes(app.RightPanel);
            title(app.UIAxes_geometry, 'Title')
            xlabel(app.UIAxes_geometry, 'X')
            ylabel(app.UIAxes_geometry, 'Y')
            zlabel(app.UIAxes_geometry, 'Z')
            app.k_rradiusofthecentralareabeingplottedLabel = uilabel(app.RightPanel);
            app.k_rradiusofthecentralareabeingplottedLabel.Interpreter = 'latex';
            app.k_rradiusofthecentralareabeingplottedLabel.Text = 'k_r (\AA^{-1}):';
            app.k_rAA1Spinner = uispinner(app.RightPanel);
            app.k_rAA1Spinner.Tooltip = {'radius of the central area being plotted'};
            app.k_rAA1Spinner.Value = 1.5;
            app.manipulator_demo = uiimage(app.RightPanel);
            app.manipulator_demo.ImageClickedFcn = createCallbackFcn(app, @manipulator_demoClicked, true);
            app.manipulator_demo.ImageSource = fullfile(pathToMLAPP, 'figures', 'manipulator_demo.png');
            app.BcenterLabel = uilabel(app.RightPanel);
            app.BcenterLabel.Text = 'B(center)=';
            app.slit_demo = uiimage(app.RightPanel);
            app.slit_demo.ImageClickedFcn = createCallbackFcn(app, @slit_demoImageClicked, true);
            app.slit_demo.Visible = 'off';
            app.slit_demo.ImageSource = fullfile(pathToMLAPP, 'figures', 'slit_demo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end